classdef LeakageMonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LeakagediagnosticmonitoringLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        OverviewTab                    matlab.ui.container.Tab
        Axes_SouthNF_Raw               matlab.ui.control.UIAxes
        Axes_NorthNF_Raw               matlab.ui.control.UIAxes
        Axes_SouthFF_Raw               matlab.ui.control.UIAxes
        Axes_NorthFF_Raw               matlab.ui.control.UIAxes
        RunningSwitchLabel             matlab.ui.control.Label
        LeakageDiagnosticMonitoringSwitch  matlab.ui.control.ToggleSwitch
        Axes_Centeroid_South           matlab.ui.control.UIAxes
        Axes_Centeroid_North           matlab.ui.control.UIAxes
        Button_InitialiseReference     matlab.ui.control.Button
        SouthdataacquiredLampLabel     matlab.ui.control.Label
        SouthdataacquiredLamp          matlab.ui.control.Lamp
        NorthdataacquiredLampLabel     matlab.ui.control.Label
        NorthdataacquiredLamp          matlab.ui.control.Lamp
        Button_InitialiseReference_2   matlab.ui.control.Button
        SouthpointingstatusLampLabel   matlab.ui.control.Label
        SouthpointingstatusLamp        matlab.ui.control.Lamp
        NorthpointingstatusLampLabel   matlab.ui.control.Label
        NorthpointingstatusLamp        matlab.ui.control.Lamp
        LastshotinfoTab                matlab.ui.container.Tab
        CurrentPointingTabularData     matlab.ui.control.Table
        ConfigurationTab               matlab.ui.container.Tab
        NFPixelspermmEditFieldLabel    matlab.ui.control.Label
        NFPixelspermmEditField         matlab.ui.control.NumericEditField
        NorthleakageLabel              matlab.ui.control.Label
        NFmJpercountEditFieldLabel     matlab.ui.control.Label
        NFmJpercountEditField          matlab.ui.control.NumericEditField
        FFPixelspermmEditFieldLabel    matlab.ui.control.Label
        FFPixelspermmEditField         matlab.ui.control.NumericEditField
        FFmJpercountEditFieldLabel     matlab.ui.control.Label
        FFmJpercountEditField          matlab.ui.control.NumericEditField
        SouthleakageLabel              matlab.ui.control.Label
        NFPixelspermmEditField_2Label  matlab.ui.control.Label
        NFPixelspermmEditField_2       matlab.ui.control.NumericEditField
        NFmJpercountEditField_2Label   matlab.ui.control.Label
        NFmJpercountEditField_2        matlab.ui.control.NumericEditField
        FFPixelspermmEditField_2Label  matlab.ui.control.Label
        FFPixelspermmEditField_2       matlab.ui.control.NumericEditField
        FFmJpercountEditField_2Label   matlab.ui.control.Label
        FFmJpercountEditField_2        matlab.ui.control.NumericEditField
        LoadconfigButton               matlab.ui.control.Button
        SaveconfigButton               matlab.ui.control.Button
        PrevpointinginfoTab            matlab.ui.container.Tab
        Axes_SouthNF_Pointing_History  matlab.ui.control.UIAxes
        RunsEditFieldLabel             matlab.ui.control.Label
        RunsEditField                  matlab.ui.control.NumericEditField
        DatesEditFieldLabel            matlab.ui.control.Label
        DatesEditField                 matlab.ui.control.NumericEditField
        Axes_SouthNF_Waist_History     matlab.ui.control.UIAxes
        Axes_SouthNF_FWHMCounts_History  matlab.ui.control.UIAxes
        Axes_NorthNF_Pointing_History  matlab.ui.control.UIAxes
        Axes_NorthNF_Waist_History     matlab.ui.control.UIAxes
        Axes_NorthNF_FWHMCounts_History  matlab.ui.control.UIAxes
        PulldataButton                 matlab.ui.control.Button
        Axes_SouthFF_Pointing_History  matlab.ui.control.UIAxes
        Axes_SouthFF_Waist_History     matlab.ui.control.UIAxes
        Axes_SouthFF_FWHMCounts_History  matlab.ui.control.UIAxes
        Axes_NorthFF_Pointing_History  matlab.ui.control.UIAxes
        Axes_NorthFF_Waist_History     matlab.ui.control.UIAxes
        Axes_NorthFF_FWHMCounts_History  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DebugMode % A switch variable which allows the testing of all subsequent functions
        NorthCameraProp
        SouthCameraProp
        NorthPointing
        SouthPointing
        
        PullDataTimer % Description
        TrendlineLimit % Description
        ReferenceIndex
        TrendlineMin % Description
        ConfigInfo % Description
    end
    
    methods (Access = private)
        function [] = AnalyseRawImage(app)
            Temp1=GetImCenteroid(app.NorthCameraProp.RawData.NF);
            Temp2=GetImCenteroid(app.NorthCameraProp.RawData.FF);
            Temp3=GetImCenteroid(app.SouthCameraProp.RawData.NF);
            Temp4=GetImCenteroid(app.SouthCameraProp.RawData.FF);
            
            %Allocate centeroid variables into the app
            % If first value create list, if not append to list
            if isempty(app.NorthPointing)
                app.NorthPointing.NF.x_centeroid=Temp1.x_bar;
                app.NorthPointing.NF.y_centeroid=Temp1.y_bar;
                app.NorthPointing.FF.x_centeroid=Temp2.x_bar;
                app.NorthPointing.FF.y_centeroid=Temp2.y_bar;
                
                app.SouthPointing.NF.x_centeroid=Temp3.x_bar;
                app.SouthPointing.NF.y_centeroid=Temp3.y_bar;
                app.SouthPointing.FF.x_centeroid=Temp4.x_bar;
                app.SouthPointing.FF.y_centeroid=Temp4.y_bar;
                
                app.NorthPointing.NF.Ref.x_centeroid=Temp1.x_bar;
                app.NorthPointing.NF.Ref.y_centeroid=Temp1.y_bar;
                app.NorthPointing.FF.Ref.x_centeroid=Temp2.x_bar;
                app.NorthPointing.FF.Ref.y_centeroid=Temp2.y_bar;
                
                app.SouthPointing.NF.Ref.x_centeroid=Temp3.x_bar;
                app.SouthPointing.NF.Ref.y_centeroid=Temp3.y_bar;
                app.SouthPointing.FF.Ref.x_centeroid=Temp4.x_bar;
                app.SouthPointing.FF.Ref.y_centeroid=Temp4.y_bar;
            else
                app.NorthPointing.NF.x_centeroid(end+1)=Temp1.x_bar;
                app.NorthPointing.NF.y_centeroid(end+1)=Temp1.y_bar;
                app.NorthPointing.FF.x_centeroid(end+1)=Temp2.x_bar;
                app.NorthPointing.FF.y_centeroid(end+1)=Temp2.y_bar;
                
                app.SouthPointing.NF.x_centeroid(end+1)=Temp3.x_bar;
                app.SouthPointing.NF.y_centeroid(end+1)=Temp3.y_bar;
                app.SouthPointing.FF.x_centeroid(end+1)=Temp4.x_bar;
                app.SouthPointing.FF.y_centeroid(end+1)=Temp4.y_bar;
                
            end
            
            
            Temp1=GetImEllipseFit(app.NorthCameraProp.RawData.NF);
            Temp2=GetImEllipseFit(app.NorthCameraProp.RawData.FF);
            Temp3=GetImEllipseFit(app.SouthCameraProp.RawData.NF);
            Temp4=GetImEllipseFit(app.SouthCameraProp.RawData.FF);
            app.NorthPointing.NF.Contour=Temp1.ImContour;
            app.NorthPointing.FF.Contour=Temp2.ImContour;
            app.SouthPointing.NF.Contour=Temp3.ImContour;
            app.SouthPointing.FF.Contour=Temp4.ImContour;
            
            %Allocate ellipse fit variables into the app
            if length(app.NorthPointing.NF.x_centeroid)==1
                app.NorthPointing.NF.Ref.Contour=Temp1.ImContour;
                app.NorthPointing.FF.Ref.Contour=Temp2.ImContour;
                app.SouthPointing.NF.Ref.Contour=Temp3.ImContour;
                app.SouthPointing.FF.Ref.Contour=Temp4.ImContour;
                
                app.NorthPointing.NF.LongAxis=Temp1.LongAxis;
                app.NorthPointing.NF.ShortAxis=Temp1.ShortAxis;
                app.NorthPointing.NF.Energy=Temp1.Energy;
                app.NorthPointing.FF.LongAxis=Temp2.LongAxis;
                app.NorthPointing.FF.ShortAxis=Temp2.ShortAxis;
                app.NorthPointing.FF.Energy=Temp2.Energy;
                
                app.SouthPointing.NF.LongAxis=Temp3.LongAxis;
                app.SouthPointing.NF.ShortAxis=Temp3.ShortAxis;
                app.SouthPointing.NF.Energy=Temp3.Energy;
                app.SouthPointing.FF.LongAxis=Temp4.LongAxis;
                app.SouthPointing.FF.ShortAxis=Temp4.ShortAxis;
                app.SouthPointing.FF.Energy=Temp4.Energy;
            else
                app.NorthPointing.NF.LongAxis(end+1)=Temp1.LongAxis;
                app.NorthPointing.NF.ShortAxis(end+1)=Temp1.ShortAxis;
                app.NorthPointing.NF.Energy.CountsInFWHM(end+1)=Temp1.Energy.CountsInFWHM;
                app.NorthPointing.NF.Energy.TotalCounts(end+1)=Temp1.Energy.TotalCounts;
                app.NorthPointing.NF.Energy.PeakCounts(end+1)=Temp1.Energy.PeakCounts;
                app.NorthPointing.FF.LongAxis(end+1)=Temp2.LongAxis;
                app.NorthPointing.FF.ShortAxis(end+1)=Temp2.ShortAxis;
                app.NorthPointing.FF.Energy.CountsInFWHM(end+1)=Temp2.Energy.CountsInFWHM;
                app.NorthPointing.FF.Energy.TotalCounts(end+1)=Temp2.Energy.TotalCounts;
                app.NorthPointing.FF.Energy.PeakCounts(end+1)=Temp2.Energy.PeakCounts;
                
                app.SouthPointing.NF.LongAxis(end+1)=Temp3.LongAxis;
                app.SouthPointing.NF.ShortAxis(end+1)=Temp3.ShortAxis;
                app.SouthPointing.NF.Energy.CountsInFWHM(end+1)=Temp3.Energy.CountsInFWHM;
                app.SouthPointing.NF.Energy.TotalCounts(end+1)=Temp3.Energy.TotalCounts;
                app.SouthPointing.NF.Energy.PeakCounts(end+1)=Temp3.Energy.PeakCounts;

                app.SouthPointing.FF.LongAxis(end+1)=Temp4.LongAxis;
                app.SouthPointing.FF.ShortAxis(end+1)=Temp4.ShortAxis;
                app.SouthPointing.FF.Energy.CountsInFWHM(end+1)=Temp4.Energy.CountsInFWHM;
                app.SouthPointing.FF.Energy.TotalCounts(end+1)=Temp4.Energy.TotalCounts;
                app.SouthPointing.FF.Energy.PeakCounts(end+1)=Temp4.Energy.PeakCounts;
            end
            
            
        end
        
        function [] = UpdatePointingTable(app)
        %North variables
            app.CurrentPointingTabularData.Data{1,1}=int16(app.NorthPointing.NF.x_centeroid(end));
            app.CurrentPointingTabularData.Data{2,1}=int16(app.NorthPointing.NF.LongAxis(end));
            app.CurrentPointingTabularData.Data{3,1}=int16(app.NorthPointing.NF.y_centeroid(end));
            app.CurrentPointingTabularData.Data{4,1}=int16(app.NorthPointing.NF.ShortAxis(end));
            app.CurrentPointingTabularData.Data{5,1}=int16(app.NorthPointing.NF.Energy.TotalCounts(end));
            app.CurrentPointingTabularData.Data{6,1}=int16(app.NorthPointing.NF.Energy.PeakCounts(end));
            app.CurrentPointingTabularData.Data{7,1}=int16(app.NorthPointing.NF.Energy.CountsInFWHM(end));            
            app.CurrentPointingTabularData.Data{8,1}=int16(app.NorthPointing.FF.x_centeroid(end));
            app.CurrentPointingTabularData.Data{9,1}=int16(app.NorthPointing.FF.LongAxis(end));
            app.CurrentPointingTabularData.Data{10,1}=int16(app.NorthPointing.FF.y_centeroid(end));
            app.CurrentPointingTabularData.Data{11,1}=int16(app.NorthPointing.FF.ShortAxis(end));
            app.CurrentPointingTabularData.Data{12,1}=int16(app.NorthPointing.FF.Energy.TotalCounts(end));
            app.CurrentPointingTabularData.Data{13,1}=int16(app.NorthPointing.FF.Energy.PeakCounts(end));
            app.CurrentPointingTabularData.Data{14,1}=int16(app.NorthPointing.FF.Energy.CountsInFWHM(end));
            %South variables
            app.CurrentPointingTabularData.Data{1,2}=int16(app.SouthPointing.NF.x_centeroid(end));
            app.CurrentPointingTabularData.Data{2,2}=int16(app.SouthPointing.NF.LongAxis(end));
            app.CurrentPointingTabularData.Data{3,2}=int16(app.SouthPointing.NF.y_centeroid(end));
            app.CurrentPointingTabularData.Data{4,2}=int16(app.SouthPointing.NF.ShortAxis(end));
            app.CurrentPointingTabularData.Data{5,2}=int16(app.SouthPointing.NF.Energy.TotalCounts(end));
            app.CurrentPointingTabularData.Data{6,2}=int16(app.SouthPointing.NF.Energy.PeakCounts(end));
            app.CurrentPointingTabularData.Data{7,2}=int16(app.SouthPointing.NF.Energy.CountsInFWHM(end));
            app.CurrentPointingTabularData.Data{8,2}=int16(app.SouthPointing.FF.x_centeroid(end));
            app.CurrentPointingTabularData.Data{9,2}=int16(app.SouthPointing.FF.LongAxis(end));
            app.CurrentPointingTabularData.Data{10,2}=int16(app.SouthPointing.FF.y_centeroid(end));
            app.CurrentPointingTabularData.Data{11,2}=int16(app.SouthPointing.FF.ShortAxis(end));
            app.CurrentPointingTabularData.Data{12,2}=int16(app.SouthPointing.FF.Energy.TotalCounts(end));
            app.CurrentPointingTabularData.Data{13,2}=int16(app.SouthPointing.FF.Energy.PeakCounts(end));
            app.CurrentPointingTabularData.Data{14,2}=int16(app.SouthPointing.FF.Energy.CountsInFWHM(end));
        end
        
        function [] = UpdatePointingTrendlineGraph(app)
        if(length(app.NorthPointing.NF.x_centeroid)>app.ConfigInfo.TrendlineLimit)
            Idx=length(app.NorthPointing.NF.x_centeroid)-app.ConfigInfo.TrendlineLimit:length(app.NorthPointing.NF.x_centeroid);
        else
            Idx=1:length(app.NorthPointing.NF.x_centeroid);
        end
        ShotNo=1:length(app.NorthPointing.NF.x_centeroid(Idx));
        
        %Plot centeroid NF and FF for north
        hold(app.Axes_Centeroid_North,'off');
        cla(app.Axes_Centeroid_North);
        yyaxis(app.Axes_Centeroid_North,'left');
        app.Axes_Centeroid_North.YColor='r';
        plot(app.Axes_Centeroid_North,ShotNo,...
            app.NorthPointing.NF.RadialPos(Idx),'rx');
        hold(app.Axes_Centeroid_North,'on');
        ylabel(app.Axes_Centeroid_North,'North NF radial pointing');
        ylim(app.Axes_Centeroid_North,...
            [0 max(app.NorthPointing.NF.RadialPos(Idx))+1]);
        
            %Generate a moving average trendline
        if length(app.NorthPointing.NF.x_centeroid)>app.ConfigInfo.TrendlineMin
            plot(app.Axes_Centeroid_North,ShotNo,...
            movmean(app.NorthPointing.NF.RadialPos(Idx),app.ConfigInfo.TrendlineMin),'r--');
        end
        
        yyaxis(app.Axes_Centeroid_North,'right');
        plot(app.Axes_Centeroid_North,ShotNo,...
            app.NorthPointing.FF.RadialPos(Idx),'kx');
        ylabel(app.Axes_Centeroid_North,'North FF radial pointing')
        ylim(app.Axes_Centeroid_North,...
            [0 max(app.NorthPointing.FF.RadialPos(Idx))+1]);
        xlim(app.Axes_Centeroid_North,[0 length(ShotNo)]);
        app.Axes_Centeroid_North.YColor='k';
        grid(app.Axes_Centeroid_North,"on");
        xlabel(app.Axes_Centeroid_North,'Shot number');
        if length(app.NorthPointing.NF.x_centeroid)>app.ConfigInfo.TrendlineMin
            plot(app.Axes_Centeroid_North,ShotNo,...
            movmean(app.NorthPointing.FF.RadialPos(Idx),app.ConfigInfo.TrendlineMin),'k--');
        end
        
        
        %Plot centeroid NF and FF for south
        hold(app.Axes_Centeroid_South,'off');
        cla(app.Axes_Centeroid_South);
        yyaxis(app.Axes_Centeroid_South,'left');
        app.Axes_Centeroid_South.YColor='r';
        plot(app.Axes_Centeroid_South,ShotNo,...
            app.SouthPointing.NF.RadialPos(Idx),'rx');
        ylabel(app.Axes_Centeroid_South,'South NF radial pointing');
        ylim(app.Axes_Centeroid_South,...
            [0 max(app.SouthPointing.NF.RadialPos(Idx))+1]);
        hold(app.Axes_Centeroid_South,'on');
        if length(app.SouthPointing.NF.x_centeroid)>app.ConfigInfo.TrendlineMin
            plot(app.Axes_Centeroid_South,ShotNo,...
            movmean(app.SouthPointing.NF.RadialPos(Idx),app.ConfigInfo.TrendlineMin),'r--');
        end
        
        yyaxis(app.Axes_Centeroid_South,'right');
        plot(app.Axes_Centeroid_South,ShotNo,...
            app.SouthPointing.FF.RadialPos(Idx),'kx');
        app.Axes_Centeroid_South.YColor='k';
        ylabel(app.Axes_Centeroid_South,'South FF radial pointing');
        if length(app.SouthPointing.FF.x_centeroid)>app.ConfigInfo.TrendlineMin
            plot(app.Axes_Centeroid_South,ShotNo,...
            movmean(app.SouthPointing.FF.RadialPos(Idx),app.ConfigInfo.TrendlineMin),'r--');
        end
        xlim(app.Axes_Centeroid_South,[0 length(ShotNo)])
        grid(app.Axes_Centeroid_South,"on");
        xlabel(app.Axes_Centeroid_South,'Shot number');
        end
        
        function [] = DrawContourAndCrossHair(app)
            XSouthNF=1:size(app.SouthCameraProp.RawData.NF,1);
            YSouthNF=1:size(app.SouthCameraProp.RawData.NF,2);
            XSouthFF=1:size(app.SouthCameraProp.RawData.FF,1);
            YSouthFF=1:size(app.SouthCameraProp.RawData.FF,2);
            
            XNorthNF=1:size(app.NorthCameraProp.RawData.NF,1);
            YNorthNF=1:size(app.NorthCameraProp.RawData.NF,2);
            XNorthFF=1:size(app.NorthCameraProp.RawData.FF,1);
            YNorthFF=1:size(app.NorthCameraProp.RawData.FF,2);
            
            %Plot the cross hairs
            plot(app.Axes_SouthNF_Raw,YSouthNF,...
                app.SouthPointing.NF.x_centeroid(end)*ones(size(YSouthNF)),'r--');
            plot(app.Axes_SouthNF_Raw,app.SouthPointing.NF.y_centeroid(end)*ones(size(XSouthNF)),...
                XSouthNF,'r--')
            
            plot(app.Axes_SouthNF_Raw,YSouthNF,...
                app.SouthPointing.NF.Ref.x_centeroid(end)*ones(size(YSouthNF)),'g--');
            plot(app.Axes_SouthNF_Raw,app.SouthPointing.NF.Ref.y_centeroid(end)*ones(size(XSouthNF)),...
                XSouthNF,'g--')
            
            plot(app.Axes_SouthFF_Raw,YSouthFF,...
                app.SouthPointing.FF.x_centeroid(end)*ones(size(YSouthFF)),'r--');
            plot(app.Axes_SouthFF_Raw,app.SouthPointing.FF.y_centeroid(end)*ones(size(XSouthFF)),...
                XSouthFF,'r--')

            plot(app.Axes_SouthFF_Raw,YSouthFF,...
                app.SouthPointing.FF.Ref.x_centeroid(end)*ones(size(YSouthFF)),'g--');
            plot(app.Axes_SouthFF_Raw,app.SouthPointing.FF.Ref.y_centeroid(end)*ones(size(XSouthFF)),...
                XSouthFF,'g--')
            
                plot(app.Axes_NorthNF_Raw,YNorthNF,...
                app.NorthPointing.NF.x_centeroid(end)*ones(size(YNorthNF)),'r--');
            plot(app.Axes_NorthNF_Raw,app.NorthPointing.NF.y_centeroid(end)*ones(size(XNorthNF)),...
                XNorthNF,'r--')
            
                plot(app.Axes_NorthNF_Raw,YNorthNF,...
                app.NorthPointing.NF.Ref.x_centeroid(end)*ones(size(YNorthNF)),'g--');
            plot(app.Axes_NorthNF_Raw,app.NorthPointing.NF.Ref.y_centeroid(end)*ones(size(XNorthNF)),...
                XNorthNF,'g--')
            
                plot(app.Axes_NorthFF_Raw,YNorthFF,...
                app.NorthPointing.FF.x_centeroid(end)*ones(size(YNorthFF)),'r--');
            plot(app.Axes_NorthFF_Raw,app.NorthPointing.FF.y_centeroid(end)*ones(size(XNorthNF)),...
                XNorthFF,'r--')
            plot(app.Axes_NorthFF_Raw,YNorthFF,...
                app.NorthPointing.FF.Ref.x_centeroid(end)*ones(size(YNorthFF)),'g--');
            plot(app.Axes_NorthFF_Raw,app.NorthPointing.FF.Ref.y_centeroid(end)*ones(size(XNorthNF)),...
                XNorthFF,'g--')
            
            %Plot the contours
            scatter(app.Axes_NorthNF_Raw,app.NorthPointing.NF.Contour(1,:),app.NorthPointing.NF.Contour(2,:),0.2,'m');
            scatter(app.Axes_NorthFF_Raw,app.NorthPointing.FF.Contour(1,:),app.NorthPointing.FF.Contour(2,:),0.2,'m');
            
            scatter(app.Axes_SouthNF_Raw,app.SouthPointing.NF.Contour(1,:),app.SouthPointing.NF.Contour(2,:),0.2,'m');
            scatter(app.Axes_SouthFF_Raw,app.SouthPointing.FF.Contour(1,:),app.SouthPointing.FF.Contour(2,:),0.2,'m');
            
            scatter(app.Axes_NorthNF_Raw,app.NorthPointing.NF.Ref.Contour(1,:),app.NorthPointing.NF.Ref.Contour(2,:),0.2,'g');
            scatter(app.Axes_NorthFF_Raw,app.NorthPointing.FF.Ref.Contour(1,:),app.NorthPointing.FF.Ref.Contour(2,:),0.2,'g');
            
            scatter(app.Axes_SouthNF_Raw,app.SouthPointing.NF.Ref.Contour(1,:),app.SouthPointing.NF.Ref.Contour(2,:),0.2,'g');
            scatter(app.Axes_SouthFF_Raw,app.SouthPointing.FF.Ref.Contour(1,:),app.SouthPointing.FF.Ref.Contour(2,:),0.2,'g');
            
        end
        
        function [] = CartesianToRadial(app)
            app.SouthPointing.NF.RadialPos=sqrt((app.SouthPointing.NF.x_centeroid-app.SouthPointing.NF.x_centeroid(app.ReferenceIndex)).^2+...
                (app.SouthPointing.NF.y_centeroid-app.SouthPointing.NF.y_centeroid(app.ReferenceIndex)).^2);
            app.SouthPointing.FF.RadialPos=sqrt((app.SouthPointing.FF.x_centeroid-app.SouthPointing.FF.x_centeroid(app.ReferenceIndex)).^2+...
                (app.SouthPointing.FF.y_centeroid-app.SouthPointing.FF.y_centeroid(app.ReferenceIndex)).^2);
            app.NorthPointing.NF.RadialPos=sqrt((app.NorthPointing.NF.x_centeroid-app.NorthPointing.NF.x_centeroid(app.ReferenceIndex)).^2+...
                (app.NorthPointing.NF.y_centeroid-app.NorthPointing.NF.y_centeroid(app.ReferenceIndex)).^2);
            app.NorthPointing.FF.RadialPos=sqrt((app.NorthPointing.FF.x_centeroid-app.NorthPointing.FF.x_centeroid(app.ReferenceIndex)).^2+...
                (app.NorthPointing.FF.y_centeroid-app.NorthPointing.FF.y_centeroid(app.ReferenceIndex)).^2);
        end
    end
    
    methods (Access = public)
        function [] = UpdateRawDataFigures(app)
            if(isempty(app.NorthCameraProp.RawData.NF))
                
            else
                hold(app.Axes_SouthNF_Raw,'off');
                image(app.Axes_SouthNF_Raw,...
                    uint8(app.SouthCameraProp.RawData.NF));
                axis(app.Axes_SouthNF_Raw,'xy');
                hold(app.Axes_SouthNF_Raw,'on');
                xlim(app.Axes_SouthNF_Raw,...
                    [0 size(app.SouthCameraProp.RawData.NF,2)]);
                ylim(app.Axes_SouthNF_Raw,...
                    [0 size(app.SouthCameraProp.RawData.NF,1)]);

                hold(app.Axes_SouthFF_Raw,'off');
                image(app.Axes_SouthFF_Raw,...
                    uint8(app.SouthCameraProp.RawData.FF));
                hold(app.Axes_SouthFF_Raw,'on');
                axis(app.Axes_SouthFF_Raw,'xy');
                xlim(app.Axes_SouthFF_Raw,...
                    [0 size(app.SouthCameraProp.RawData.FF,2)]);
                ylim(app.Axes_SouthNF_Raw,...
                    [0 size(app.SouthCameraProp.RawData.FF,1)]);
                
                    hold(app.Axes_NorthNF_Raw,'off');
                image(app.Axes_NorthNF_Raw,...
                    uint8(app.NorthCameraProp.RawData.NF));
                hold(app.Axes_NorthNF_Raw,'on');
                axis(app.Axes_NorthNF_Raw,'xy');
                xlim(app.Axes_NorthNF_Raw,...
                    [0 size(app.NorthCameraProp.RawData.NF,2)]);
                ylim(app.Axes_NorthNF_Raw,...
                    [0 size(app.NorthCameraProp.RawData.NF,1)]);
                
                    hold(app.Axes_NorthFF_Raw,'off');
                image(app.Axes_NorthFF_Raw,...
                    uint8(app.NorthCameraProp.RawData.FF));
                hold(app.Axes_NorthFF_Raw,'on');
                axis(app.Axes_NorthFF_Raw,'xy');
                xlim(app.Axes_NorthFF_Raw,...
                    [0 size(app.NorthCameraProp.RawData.FF,2)]);
                ylim(app.Axes_NorthFF_Raw,...
                    [0 size(app.NorthCameraProp.RawData.FF,1)]);
            end
        end
        
        function [] = PullImageData(app)
            if app.DebugMode
                app.NorthCameraProp.RawData.NF=GenerateFakeData('NF');
                app.NorthCameraProp.RawData.FF=GenerateFakeData('FF');
                app.SouthCameraProp.RawData.NF=GenerateFakeData('NF');
                app.SouthCameraProp.RawData.FF=GenerateFakeData('FF');
            else
                error('Function is incomplete! Do not use.')
            end
        end
        
        function results = IsNewDataAvailable(app)
            if app.DebugMode
                %Don't bother checking. Just pull from fake data
                 PullImageData(app);
                 AnalyseRawImage(app)
                 UpdateRawDataFigures(app);
                 UpdatePointingTable(app)
                 CartesianToRadial(app);
                 UpdatePointingTrendlineGraph(app);
                 DrawContourAndCrossHair(app);
                 results=1;
            else
                error('Function is incomplete! Do not use.')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        addpath ImageAnalysisFunctions    
        app.DebugMode=1;
            
            if app.DebugMode
                addpath DebugFunctions                
                %Initialise pointing table information
                for i=1:8
                    for j=1:2
                        app.CurrentPointingTabularData.Data{i,j}=NaN;
                    end
                end 
            else
                warning('Startup function is not set for non-debug mode')
                
            end
            %Initialise variables            
            app.NorthCameraProp.RawData.NF=[];
            app.NorthCameraProp.RawData.FF=[];
            app.SouthCameraProp.RawData.NF=[];
            app.SouthCameraProp.RawData.FF=[];
            app.PullDataTimer = timer('Period',1,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.PullDataTimer.TimerFcn = @(~,~) app.IsNewDataAvailable();
            app.ReferenceIndex=1;
            
            %Set configuration information
            app.ConfigInfo.North.NF.pixelspermm=1;
            app.ConfigInfo.North.NF.mJpercount=1;
            app.ConfigInfo.North.FF.mJpercount=1;
            app.ConfigInfo.North.FF.mJpercount=1;
            
            app.ConfigInfo.South.NF.pixelspermm=1;
            app.ConfigInfo.South.NF.mJpercount=1;
            app.ConfigInfo.South.FF.mJpercount=1;
            app.ConfigInfo.South.FF.mJpercount=1;
            
            app.ConfigInfo.TrendlineLimit=50;
            app.ConfigInfo.TrendlineMin=50;
            
            
        end

        % Value changed function: LeakageDiagnosticMonitoringSwitch
        function LeakageDiagnosticMonitoringSwitchValueChanged(app, event)
            value = app.LeakageDiagnosticMonitoringSwitch.Value;
            if strcmp(value,'On')
                start(app.PullDataTimer);
            else
                stop(app.PullDataTimer);
            end
        end

        % Button pushed function: Button_InitialiseReference
        function Button_InitialiseReferencePushed(app, event)
            app.ReferenceIndex=length(app.NorthPointing.NF.x_centeroid);
            app.NorthPointing.NF.Ref.x_centeroid=app.NorthPointing.NF.x_centeroid;
            app.NorthPointing.NF.Ref.y_centeroid=app.NorthPointing.NF.y_centeroid;
            app.NorthPointing.FF.Ref.x_centeroid=app.NorthPointing.FF.x_centeroid;
            app.NorthPointing.FF.Ref.y_centeroid=app.NorthPointing.FF.y_centeroid;
            
            app.SouthPointing.NF.Ref.x_centeroid=app.SouthPointing.NF.x_centeroid;
            app.SouthPointing.NF.Ref.y_centeroid=app.SouthPointing.NF.y_centeroid;
            app.SouthPointing.FF.Ref.x_centeroid=app.SouthPointing.FF.x_centeroid;
            app.SouthPointing.FF.Ref.y_centeroid=app.SouthPointing.FF.y_centeroid;
            
            app.NorthPointing.NF.Ref.Contour=app.NorthPointing.NF.Contour;
            app.NorthPointing.FF.Ref.Contour=app.NorthPointing.FF.Contour;
            app.SouthPointing.NF.Ref.Contour=app.SouthPointing.NF.Contour;
            app.SouthPointing.FF.Ref.Contour=app.SouthPointing.FF.Contour;
        end

        % Button pushed function: Button_InitialiseReference_2
        function Button_InitialiseReference_2Pushed(app, event)
            app.SouthPointing.NF.x_centeroid=app.SouthPointing.NF.x_centeroid(end);
            app.SouthPointing.NF.y_centeroid=app.SouthPointing.NF.y_centeroid(end);
            app.SouthPointing.FF.x_centeroid=app.SouthPointing.FF.x_centeroid(end);
            app.SouthPointing.FF.y_centeroid=app.SouthPointing.FF.y_centeroid(end);
            
            app.NorthPointing.NF.x_centeroid=app.NorthPointing.NF.x_centeroid(end);
            app.NorthPointing.NF.y_centeroid=app.NorthPointing.NF.y_centeroid(end);
            app.NorthPointing.FF.x_centeroid=app.NorthPointing.FF.x_centeroid(end);
            app.NorthPointing.FF.y_centeroid=app.NorthPointing.FF.y_centeroid(end);
        end

        % Button pushed function: SaveconfigButton
        function SaveconfigButtonPushed(app, event)
             [file, ~] = uiputfile({'*.mat'});
             Config=app.ConfigInfo;
             Config.TimeSaved=datetime();
            save(file,'Config');
        end

        % Value changed function: NFPixelspermmEditField
        function NFPixelspermmEditFieldValueChanged(app, event)
            value = app.NFPixelspermmEditField.Value;
            
        end

        % Button pushed function: LoadconfigButton
        function LoadconfigButtonPushed(app, event)
            [file, ~] = uigetfile({'*.mat'});
            Config=load(file);
            if isfield(Config,'TimeSaved')
                app.Config=Config;
            else
                warning('File type is invalid')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LeakagediagnosticmonitoringLabel = uilabel(app.UIFigure);
            app.LeakagediagnosticmonitoringLabel.Text = 'Leakage diagnostic monitoring';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.Axes_SouthNF_Raw = uiaxes(app.OverviewTab);
            title(app.Axes_SouthNF_Raw, 'South NF')
            xlabel(app.Axes_SouthNF_Raw, 'X pixel')
            ylabel(app.Axes_SouthNF_Raw, 'Y pixel')
            app.Axes_NorthNF_Raw = uiaxes(app.OverviewTab);
            title(app.Axes_NorthNF_Raw, 'North NF')
            xlabel(app.Axes_NorthNF_Raw, 'X pixel')
            ylabel(app.Axes_NorthNF_Raw, 'Y pixel')
            app.Axes_SouthFF_Raw = uiaxes(app.OverviewTab);
            title(app.Axes_SouthFF_Raw, 'South FF')
            xlabel(app.Axes_SouthFF_Raw, 'X pixel')
            ylabel(app.Axes_SouthFF_Raw, 'Y pixel')
            app.Axes_NorthFF_Raw = uiaxes(app.OverviewTab);
            title(app.Axes_NorthFF_Raw, 'North FF')
            xlabel(app.Axes_NorthFF_Raw, 'X pixel')
            ylabel(app.Axes_NorthFF_Raw, 'Y pixel')
            app.RunningSwitchLabel = uilabel(app.OverviewTab);
            app.RunningSwitchLabel.Text = 'Running';
            app.LeakageDiagnosticMonitoringSwitch = uiswitch(app.OverviewTab, 'toggle');
            app.LeakageDiagnosticMonitoringSwitch.ValueChangedFcn = createCallbackFcn(app, @LeakageDiagnosticMonitoringSwitchValueChanged, true);
            app.Axes_Centeroid_South = uiaxes(app.OverviewTab);
            title(app.Axes_Centeroid_South, 'North centeroid')
            xlabel(app.Axes_Centeroid_South, 'X')
            ylabel(app.Axes_Centeroid_South, 'Y')
            app.Axes_Centeroid_North = uiaxes(app.OverviewTab);
            title(app.Axes_Centeroid_North, 'South centeroid')
            xlabel(app.Axes_Centeroid_North, 'X')
            ylabel(app.Axes_Centeroid_North, 'Y')
            app.Button_InitialiseReference = uibutton(app.OverviewTab, 'push');
            app.Button_InitialiseReference.ButtonPushedFcn = createCallbackFcn(app, @Button_InitialiseReferencePushed, true);
            app.Button_InitialiseReference.Text = 'Set Reference';
            app.SouthdataacquiredLampLabel = uilabel(app.OverviewTab);
            app.SouthdataacquiredLampLabel.Text = 'South data acquired';
            app.SouthdataacquiredLamp = uilamp(app.OverviewTab);
            app.NorthdataacquiredLampLabel = uilabel(app.OverviewTab);
            app.NorthdataacquiredLampLabel.Text = 'North data acquired';
            app.NorthdataacquiredLamp = uilamp(app.OverviewTab);
            app.Button_InitialiseReference_2 = uibutton(app.OverviewTab, 'push');
            app.Button_InitialiseReference_2.ButtonPushedFcn = createCallbackFcn(app, @Button_InitialiseReference_2Pushed, true);
            app.Button_InitialiseReference_2.Text = 'Clear figures';
            app.SouthpointingstatusLampLabel = uilabel(app.OverviewTab);
            app.SouthpointingstatusLampLabel.Text = 'South pointing status';
            app.SouthpointingstatusLamp = uilamp(app.OverviewTab);
            app.NorthpointingstatusLampLabel = uilabel(app.OverviewTab);
            app.NorthpointingstatusLampLabel.Text = 'North pointing status';
            app.NorthpointingstatusLamp = uilamp(app.OverviewTab);
            app.LastshotinfoTab = uitab(app.TabGroup);
            app.LastshotinfoTab.Title = 'Last shot info';
            app.CurrentPointingTabularData = uitable(app.LastshotinfoTab);
            app.CurrentPointingTabularData.ColumnName = {'North'; 'South'};
            app.CurrentPointingTabularData.RowName = {'NF X pos'; 'NF X waist'; 'NF Y pos'; 'NF Y waist'; 'NF total en'; 'NF peak'; 'NF fwhm en'; ' FF X pos'; ' FF X waist'; 'FF Y pos'; 'FF Y waist'; 'FF total en'; 'FF peak'; 'FF fwhm en'};
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.NFPixelspermmEditFieldLabel = uilabel(app.ConfigurationTab);
            app.NFPixelspermmEditFieldLabel.Text = 'NF Pixels per mm';
            app.NFPixelspermmEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.NFPixelspermmEditField.Limits = [0 1000];
            app.NFPixelspermmEditField.ValueChangedFcn = createCallbackFcn(app, @NFPixelspermmEditFieldValueChanged, true);
            app.NFPixelspermmEditField.Value = 1;
            app.NorthleakageLabel = uilabel(app.ConfigurationTab);
            app.NorthleakageLabel.Text = 'North leakage';
            app.NFmJpercountEditFieldLabel = uilabel(app.ConfigurationTab);
            app.NFmJpercountEditFieldLabel.Text = 'NF mJ per count';
            app.NFmJpercountEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.NFmJpercountEditField.Limits = [0 Inf];
            app.NFmJpercountEditField.Value = 1;
            app.FFPixelspermmEditFieldLabel = uilabel(app.ConfigurationTab);
            app.FFPixelspermmEditFieldLabel.Text = 'FF Pixels per mm';
            app.FFPixelspermmEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.FFPixelspermmEditField.Limits = [0 1000];
            app.FFPixelspermmEditField.Value = 1;
            app.FFmJpercountEditFieldLabel = uilabel(app.ConfigurationTab);
            app.FFmJpercountEditFieldLabel.Text = 'FF mJ per count';
            app.FFmJpercountEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.FFmJpercountEditField.Limits = [0 Inf];
            app.FFmJpercountEditField.Value = 1;
            app.SouthleakageLabel = uilabel(app.ConfigurationTab);
            app.SouthleakageLabel.Text = 'South leakage';
            app.NFPixelspermmEditField_2Label = uilabel(app.ConfigurationTab);
            app.NFPixelspermmEditField_2Label.Text = 'NF Pixels per mm';
            app.NFPixelspermmEditField_2 = uieditfield(app.ConfigurationTab, 'numeric');
            app.NFPixelspermmEditField_2.Limits = [0 1000];
            app.NFPixelspermmEditField_2.Value = 1;
            app.NFmJpercountEditField_2Label = uilabel(app.ConfigurationTab);
            app.NFmJpercountEditField_2Label.Text = 'NF mJ per count';
            app.NFmJpercountEditField_2 = uieditfield(app.ConfigurationTab, 'numeric');
            app.NFmJpercountEditField_2.Limits = [0 Inf];
            app.NFmJpercountEditField_2.Value = 1;
            app.FFPixelspermmEditField_2Label = uilabel(app.ConfigurationTab);
            app.FFPixelspermmEditField_2Label.Text = 'FF Pixels per mm';
            app.FFPixelspermmEditField_2 = uieditfield(app.ConfigurationTab, 'numeric');
            app.FFPixelspermmEditField_2.Limits = [0 1000];
            app.FFPixelspermmEditField_2.Value = 1;
            app.FFmJpercountEditField_2Label = uilabel(app.ConfigurationTab);
            app.FFmJpercountEditField_2Label.Text = 'FF mJ per count';
            app.FFmJpercountEditField_2 = uieditfield(app.ConfigurationTab, 'numeric');
            app.FFmJpercountEditField_2.Limits = [0 Inf];
            app.FFmJpercountEditField_2.Value = 1;
            app.LoadconfigButton = uibutton(app.ConfigurationTab, 'push');
            app.LoadconfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadconfigButtonPushed, true);
            app.LoadconfigButton.Text = 'Load config';
            app.SaveconfigButton = uibutton(app.ConfigurationTab, 'push');
            app.SaveconfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveconfigButtonPushed, true);
            app.SaveconfigButton.Text = 'Save config';
            app.PrevpointinginfoTab = uitab(app.TabGroup);
            app.PrevpointinginfoTab.Title = 'Previous pointing information';
            app.Axes_SouthNF_Pointing_History = uiaxes(app.PrevpointinginfoTab);
            title(app.Axes_SouthNF_Pointing_History, 'South NF pointing')
            xlabel(app.Axes_SouthNF_Pointing_History, '')
            ylabel(app.Axes_SouthNF_Pointing_History, 'Y')
            app.Axes_SouthNF_Pointing_History.XTick = [];
            app.RunsEditFieldLabel = uilabel(app.PrevpointinginfoTab);
            app.RunsEditFieldLabel.Text = 'Run(s)';
            app.RunsEditField = uieditfield(app.PrevpointinginfoTab, 'numeric');
            app.DatesEditFieldLabel = uilabel(app.PrevpointinginfoTab);
            app.DatesEditFieldLabel.Text = 'Date(s)';
            app.DatesEditField = uieditfield(app.PrevpointinginfoTab, 'numeric');
            app.Axes_SouthNF_Waist_History = uiaxes(app.PrevpointinginfoTab);
            title(app.Axes_SouthNF_Waist_History, 'South NF waist')
            xlabel(app.Axes_SouthNF_Waist_History, 'Date-time')
            ylabel(app.Axes_SouthNF_Waist_History, 'Y')
            app.Axes_SouthNF_FWHMCounts_History = uiaxes(app.PrevpointinginfoTab);
            title(app.Axes_SouthNF_FWHMCounts_History, 'South NF FWHM counts')
            xlabel(app.Axes_SouthNF_FWHMCounts_History, 'Date-time')
            ylabel(app.Axes_SouthNF_FWHMCounts_History, 'Y')
            app.Axes_NorthNF_Pointing_History = uiaxes(app.PrevpointinginfoTab);
            title(app.Axes_NorthNF_Pointing_History, 'North NF pointing')
            xlabel(app.Axes_NorthNF_Pointing_History, '')
            ylabel(app.Axes_NorthNF_Pointing_History, {''; ''})
            app.Axes_NorthNF_Pointing_History.XTick = [];
            app.Axes_NorthNF_Waist_History = uiaxes(app.PrevpointinginfoTab);
            title(app.Axes_NorthNF_Waist_History, 'North NF waist')
            xlabel(app.Axes_NorthNF_Waist_History, 'Date-time')
            ylabel(app.Axes_NorthNF_Waist_History, {''; ''})
            app.Axes_NorthNF_FWHMCounts_History = uiaxes(app.PrevpointinginfoTab);
            title(app.Axes_NorthNF_FWHMCounts_History, 'North NF FWHM counts')
            xlabel(app.Axes_NorthNF_FWHMCounts_History, 'Date-time')
            ylabel(app.Axes_NorthNF_FWHMCounts_History, {''; ''})
            app.PulldataButton = uibutton(app.PrevpointinginfoTab, 'push');
            app.PulldataButton.Text = 'Pull data';
            app.Axes_SouthFF_Pointing_History = uiaxes(app.PrevpointinginfoTab);
            title(app.Axes_SouthFF_Pointing_History, 'South FF pointing')
            xlabel(app.Axes_SouthFF_Pointing_History, '')
            ylabel(app.Axes_SouthFF_Pointing_History, 'Y')
            app.Axes_SouthFF_Pointing_History.XTick = [];
            app.Axes_SouthFF_Waist_History = uiaxes(app.PrevpointinginfoTab);
            title(app.Axes_SouthFF_Waist_History, 'South FF waist')
            xlabel(app.Axes_SouthFF_Waist_History, 'Date-time')
            ylabel(app.Axes_SouthFF_Waist_History, 'Y')
            app.Axes_SouthFF_FWHMCounts_History = uiaxes(app.PrevpointinginfoTab);
            title(app.Axes_SouthFF_FWHMCounts_History, 'South FF FWHM counts')
            xlabel(app.Axes_SouthFF_FWHMCounts_History, 'Date-time')
            ylabel(app.Axes_SouthFF_FWHMCounts_History, 'Y')
            app.Axes_NorthFF_Pointing_History = uiaxes(app.PrevpointinginfoTab);
            title(app.Axes_NorthFF_Pointing_History, 'North FF pointing')
            xlabel(app.Axes_NorthFF_Pointing_History, '')
            ylabel(app.Axes_NorthFF_Pointing_History, {''; ''})
            app.Axes_NorthFF_Pointing_History.XTick = [];
            app.Axes_NorthFF_Waist_History = uiaxes(app.PrevpointinginfoTab);
            title(app.Axes_NorthFF_Waist_History, 'North FF waist')
            xlabel(app.Axes_NorthFF_Waist_History, 'Date-time')
            ylabel(app.Axes_NorthFF_Waist_History, {''; ''})
            app.Axes_NorthFF_FWHMCounts_History = uiaxes(app.PrevpointinginfoTab);
            title(app.Axes_NorthFF_FWHMCounts_History, 'North FF FWHM counts')
            xlabel(app.Axes_NorthFF_FWHMCounts_History, 'Date-time')
            ylabel(app.Axes_NorthFF_FWHMCounts_History, {''; ''})
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeakageMonitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end